classdef vrControlCustomExpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        InputexperimentdetailsLabel  matlab.ui.control.Label
        STARTEXPERIMENTButton        matlab.ui.control.Button
        WheelgainEditField           matlab.ui.control.EditField
        WheelgainEditFieldLabel      matlab.ui.control.Label
        TrainingModeCheckBox         matlab.ui.control.CheckBox
        LoadcustomexperimentButton   matlab.ui.control.Button
        SessionEditField             matlab.ui.control.NumericEditField
        SessionEditFieldLabel        matlab.ui.control.Label
        AnimalEditField              matlab.ui.control.EditField
        AnimalEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        expInfo = []; % the expInfo strut to be loaded
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AnimalEditField
        function AnimalEditFieldValueChanged(app, event)
            value = app.AnimalEditField.Value;
            
        end

        % Button pushed function: LoadcustomexperimentButton
        function LoadcustomexperimentButtonPushed(app, event)
            % Open a UI dialog box to load the .mat file for the custom
            % experiment
            uiopen('*.mat')
            if exist('expInfo','var')
                % Edit property for expInfo
                app.expInfo = expInfo;
            else
                app.LoadcustomexperimentButton.FontColor = 'r';
                % Maybe also add something in the text box
            end
        end

        % Button pushed function: STARTEXPERIMENTButton
        function STARTEXPERIMENTButtonPushed(app, event)
            runCustomExperiment(...
                app.expInfo, ...
                app.AnimalEditField.Value, ...
                app.SessionEditField.Value, ...
                app.TrainingModeCheckBox.Value, ...
                app.WheelgainEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnimalEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalEditFieldLabel.Text = 'Animal';
            app.AnimalEditField = uieditfield(app.UIFigure, 'text');
            app.AnimalEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalEditFieldValueChanged, true);
            app.AnimalEditField.Value = 'test';
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session #';
            app.SessionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionEditField.Value = 700;
            app.LoadcustomexperimentButton = uibutton(app.UIFigure, 'push');
            app.LoadcustomexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcustomexperimentButtonPushed, true);
            app.LoadcustomexperimentButton.Text = 'Load custom experiment';
            app.TrainingModeCheckBox = uicheckbox(app.UIFigure);
            app.TrainingModeCheckBox.Text = 'Training Mode';
            app.TrainingModeCheckBox.Value = true;
            app.WheelgainEditFieldLabel = uilabel(app.UIFigure);
            app.WheelgainEditFieldLabel.Text = 'Wheel gain';
            app.WheelgainEditField = uieditfield(app.UIFigure, 'text');
            app.WheelgainEditField.InputType = 'digits';
            app.WheelgainEditField.Value = '1';
            app.STARTEXPERIMENTButton = uibutton(app.UIFigure, 'push');
            app.STARTEXPERIMENTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTEXPERIMENTButtonPushed, true);
            app.STARTEXPERIMENTButton.Text = 'START EXPERIMENT!';
            app.InputexperimentdetailsLabel = uilabel(app.UIFigure);
            app.InputexperimentdetailsLabel.Text = '1) Input experiment details:';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '3)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrControlCustomExpGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end